classdef DataVisualization_AppDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool                       matlab.ui.container.toolbar.PushTool
        DebugToggleTool                matlab.ui.container.toolbar.ToggleTool
        HelpPushTool                   matlab.ui.container.toolbar.PushTool
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        AnalyzeTab                     matlab.ui.container.Tab
        AnalyzeLayout                  matlab.ui.container.GridLayout
        VersionLabel                   matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        ProcessDataButton              matlab.ui.control.Button
        SettingsPanel                  matlab.ui.container.Panel
        AnalyzeSettingsLayout          matlab.ui.container.GridLayout
        IALiRTPatternEditField         matlab.ui.control.EditField
        IALiRTPatternEditFieldLabel    matlab.ui.control.Label
        MetaDataPatternEditField       matlab.ui.control.EditField
        MetaDataPatternEditFieldLabel  matlab.ui.control.Label
        EventPatternEditField          matlab.ui.control.EditField
        EventPatternEditFieldLabel     matlab.ui.control.Label
        HKPatternEditField             matlab.ui.control.EditField
        HKPatternEditFieldLabel        matlab.ui.control.Label
        SciencePatternEditField        matlab.ui.control.EditField
        SciencePatternEditFieldLabel   matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        LocationEditField              matlab.ui.control.EditField
        LocationEditFieldLabel         matlab.ui.control.Label
        ResultsTab                     matlab.ui.container.Tab
        ResultsLayout                  matlab.ui.container.GridLayout
        MetaDataPanel                  matlab.ui.container.Panel
        MetaDataLayout                 matlab.ui.container.GridLayout
        SecondaryTextArea              matlab.ui.control.TextArea
        PrimaryTextArea                matlab.ui.control.TextArea
        InstrumentTextArea             matlab.ui.control.TextArea
        ProcessingStepsPanel           matlab.ui.container.Panel
        StepsLayout                    matlab.ui.container.GridLayout
        RampTextArea                   matlab.ui.control.TextArea
        HKTextArea                     matlab.ui.control.TextArea
        ScienceTextArea                matlab.ui.control.TextArea
        WholeDataTextArea              matlab.ui.control.TextArea
        RampDropDown                   matlab.ui.control.DropDown
        RampModeDropDownLabel          matlab.ui.control.Label
        HKDropDown                     matlab.ui.control.DropDown
        HKDropDownLabel                matlab.ui.control.Label
        ScienceDropDown                matlab.ui.control.DropDown
        ScienceDropDownLabel           matlab.ui.control.Label
        WholeDataDropDown              matlab.ui.control.DropDown
        WholeDataDropDownLabel         matlab.ui.control.Label
        PerFileTextArea                matlab.ui.control.TextArea
        PerFileDropDown                matlab.ui.control.DropDown
        PerFileLabel                   matlab.ui.control.Label
        ExportTab                      matlab.ui.container.Tab
        ExportLayout                   matlab.ui.container.GridLayout
        ExportSettingsPanel            matlab.ui.container.Panel
        ExportSettingsLayout           matlab.ui.container.GridLayout
        EndTimeEditField               matlab.ui.control.EditField
        EndDateTimeDatePicker          matlab.ui.control.DatePicker
        EndDateTimeDatePickerLabel     matlab.ui.control.Label
        StartTimeEditField             matlab.ui.control.EditField
        StartDateTimeDatePicker        matlab.ui.control.DatePicker
        StartDateTimeDatePickerLabel   matlab.ui.control.Label
        ExportButtonsLayout            matlab.ui.container.GridLayout
        ExportNoteLabel                matlab.ui.control.Label
        ExportButton                   matlab.ui.control.Button
        FormatDropDown                 matlab.ui.control.DropDown
        FormatDropDownLabel            matlab.ui.control.Label
        VisualizeTab                   matlab.ui.container.Tab
        VisualizeLayout                matlab.ui.container.GridLayout
        VisualizationOptionsLayout     matlab.ui.container.GridLayout
        VisualizationOptionsPanel      matlab.ui.container.Panel
        VisualizationTypeListBox       matlab.ui.control.ListBox
        VisualizeButtonsLayout         matlab.ui.container.GridLayout
        CloseFiguresButton             matlab.ui.control.Button
        SaveFiguresButton              matlab.ui.control.Button
        ShowFiguresButton              matlab.ui.control.Button
    end

    properties (Access = private)
        PreviousError MException {mustBeScalarOrEmpty}
        DebugStatus struct = dbstatus()
        SelectedControl mag.app.control.Control {mustBeScalarOrEmpty}
    end

    properties (SetObservable, Access = private)
        Analysis mag.IMAPTestingAnalysis {mustBeScalarOrEmpty}
        Figures (1, :) matlab.ui.Figure
    end

    properties (Dependent, Access = private)
        ResultsLocation (1, 1) string {mustBeFolder}
    end

    methods

        function value = get.ResultsLocation(app)

            if isempty(app.Analysis)
                location = app.LocationEditField.Value;
            else
                location = app.Analysis.Location;
            end

            value = fullfile(location, compose("Results (v%s)", mag.version()));

            if ~isfolder(value)
                mkdir(value);
            end
        end
    end

    methods (Access = private)

        function analysisChanged(app, ~, ~)

            resultsAvailable = ~isempty(app.Analysis) && ~isempty(app.Analysis.Results.Science);

            % Enable/disable buttons.
            status = matlab.lang.OnOffSwitchState(resultsAvailable);

            [app.FormatDropDown.Enable, app.ExportButton.Enable, app.ShowFiguresButton.Enable, ...
                app.MetaDataPanel.Enable, app.ProcessingStepsPanel.Enable, ...
                app.ExportSettingsPanel.Enable, ...
                app.VisualizationTypeListBox.Enable, app.VisualizationOptionsPanel.Enable] = deal(status);

            % Set values in app.
            if resultsAvailable

                results = app.Analysis.Results;

                instrumentMetaData = compose("%s - BSW: %s - ASW: %s", results.MetaData.Model, results.MetaData.BSW, results.MetaData.ASW);
                primaryMetaData = compose("%s (%s - %s - %s)", results.Primary.MetaData.getDisplay("Sensor"), results.Primary.MetaData.Setup.FEE, results.Primary.MetaData.Setup.Model, results.Primary.MetaData.Setup.Can);
                secondaryMetaData = compose("%s (%s - %s - %s)", results.Secondary.MetaData.getDisplay("Sensor"), results.Secondary.MetaData.Setup.FEE, results.Secondary.MetaData.Setup.Model, results.Secondary.MetaData.Setup.Can);

                if ~isempty(instrumentMetaData)
                    app.InstrumentTextArea.Value = instrumentMetaData;
                end

                if ~isempty(primaryMetaData)
                    app.PrimaryTextArea.Value = primaryMetaData;
                end

                if ~isempty(secondaryMetaData)
                    app.SecondaryTextArea.Value = secondaryMetaData;
                end

                for i = ["PerFile", "WholeData", "Science", "HK", "Ramp"]

                    app.(i + "DropDown").Items = [app.Analysis.(i + "Processing").Name];
                    app.(i + "DropDown").ItemsData = app.Analysis.(i + "Processing");
                    app.(i + "DropDownValueChanged")();
                end

                app.VisualizationTypeListBoxValueChanged([]);
            else

                app.InstrumentTextArea.Value = char.empty();
                app.PrimaryTextArea.Value = char.empty();
                app.SecondaryTextArea.Value = char.empty();

                for i = ["PerFile", "WholeData", "Science", "HK", "Ramp"]

                    app.(i + "DropDown").Items = "";
                    app.(i + "DropDown").ItemsData = [];
                    app.(i + "TextArea").Value = char.empty();
                end

                app.PSDStartDateTimeDatePicker.Value = NaT();
                app.PSDStartTimeEditField.Value = string.empty();
            end
        end

        function figuresChanged(app, varargin)

            figuresAvailable = ~isempty(app.Figures);
            [app.SaveFiguresButton.Enable, app.CloseFiguresButton.Enable] = deal(matlab.lang.OnOffSwitchState(figuresAvailable));
        end

        function displayAlert(app, message, title, icon)

            arguments
                app
                message (1, 1) {mustBeA(message, ["string", "MException"])}
                title (1, 1) string = "Something Went Wrong..."
                icon (1, 1) string {mustBeMember(icon, ["error", "warning", "info", "success", "none"])} = "error"
            end

            if isa(message, "MException")

                app.PreviousError = message;
                msg = message.message;
            else
                msg = message;
            end

            uialert(app.UIFigure, msg, title, Icon = icon, Interpreter = "html");
        end

        function closeProgressBar = overlayProgressBar(app, message)

            arguments (Input)
                app
                message (1, 1) string
            end

            arguments (Output)
                closeProgressBar (1, 2) onCleanup
            end

            progressBar = uiprogressdlg(app.UIFigure, Message = message, Icon = "info", Indeterminate = "on");
            closeProgressBar = [onCleanup(@() delete(progressBar)), onCleanup(@() beep())];
        end

        function updateProcessingStepUI(app, name)

            value = app.(name + "DropDown").Value;

            if ~isempty(value)
                app.(name + "TextArea").Value = value.DetailedDescription;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Set app version.
            app.VersionLabel.Text = compose("Version %s", mag.version());

            % Set default patterns.
            dummyAnalysis = mag.IMAPTestingAnalysis();

            app.LocationEditField.Value = string.empty();
            app.EventPatternEditField.Value = join(dummyAnalysis.EventPattern, pathsep());
            app.MetaDataPatternEditField.Value = join(dummyAnalysis.MetaDataPattern, pathsep());
            app.SciencePatternEditField.Value = dummyAnalysis.SciencePattern;
            app.IALiRTPatternEditField.Value = dummyAnalysis.IALiRTPattern;
            app.HKPatternEditField.Value = join(dummyAnalysis.HKPattern, pathsep());

            % Subscribe to properties.
            app.addlistener("Analysis", "PostSet", @app.analysisChanged);
            app.addlistener("Figures", "PostSet", @app.figuresChanged);
        end

        % Button pushed function: ProcessDataButton
        function ProcessDataButtonPushed(app, event)

            % Validate location.
            location = app.LocationEditField.Value;
            if isempty(location)

                app.displayAlert("Location is empty.", "Invalid Location");
                return;
            elseif ~isfolder(location)

                app.displayAlert(compose("Location ""%s"" does not exist.", location), "Invalid Location");
                return;
            end

            % Show progress bar.
            closeProgressBar = app.overlayProgressBar("Processing data..."); %#ok<NASGU>

            % Disable warning backtraces.
            previousWarningState = warning("off", "backtrace");
            restoreWarningState = onCleanup(@() warning(previousWarningState));

            % Retrieve data file patterns.
            if isempty(app.EventPatternEditField.Value)
                eventPattern = string.empty();
            else
                eventPattern = split(app.EventPatternEditField.Value, pathsep())';
            end

            if isempty(app.MetaDataPatternEditField.Value)
                metaDataPattern = string.empty();
            else
                metaDataPattern = split(app.MetaDataPatternEditField.Value, pathsep())';
            end

            if isempty(app.HKPatternEditField.Value)
                hkPattern = string.empty();
            else
                hkPattern = split(app.HKPatternEditField.Value, pathsep())';
            end

            % Start analysis.
            try

                app.Analysis = mag.IMAPTestingAnalysis.start(Location = app.LocationEditField.Value, ...
                    EventPattern = eventPattern, ...
                    MetaDataPattern = metaDataPattern, ...
                    SciencePattern = app.SciencePatternEditField.Value, ...
                    IALiRTPattern = app.IALiRTPatternEditField.Value, ...
                    HKPattern = hkPattern);
            catch exception

                app.displayAlert(exception);
                return;
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)

            closeProgressBar = app.overlayProgressBar("Exporting..."); %#ok<NASGU>

            format = app.FormatDropDown.Value;

            switch format
                case "Workspace"

                    if evalin("base", "exist(""analysis"", ""var"")")

                        selectedOption = uiconfirm(app.UIFigure, "Variable <code>analysis</code> already exists in the MATLAB Workspace." + ...
                            " Would you like to overwrite it?", "Variable Already Exists", Interpreter = "html");

                        if ~isequal(selectedOption, "OK")
                            return;
                        end
                    end

                    assignin("base", "analysis", app.Analysis);
                    return;
                case "MAT (Full Analysis)"

                    analysis = app.Analysis;
                    save(fullfile(app.ResultsLocation, "Data.mat"), "analysis");
                    return;
                case "MAT (Science Lead)"
                    exportType = "MAT";
                case "CDF"
                    exportType = "CDF";
                otherwise
                    app.displayAlert(compose("Unrecognized export format option ""%s"".", format));
            end

            try

                startTime = mag.app.internal.combineDateAndTime(app.StartDateTimeDatePicker.Value, app.StartTimeEditField.Value);
                endTime = mag.app.internal.combineDateAndTime(app.EndDateTimeDatePicker.Value, app.EndTimeEditField.Value);

                app.Analysis.export(exportType, Location = app.ResultsLocation, StartTime = startTime, EndTime = endTime);
            catch exception
                app.displayAlert(exception);
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)

            location = uigetdir(app.LocationEditField.Value, "Select Data Root");

            if ~isequal(location, 0)
                app.LocationEditField.Value = location;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            app.startupFcn();
            app.CloseFiguresButtonPushed(event);

            app.Analysis = mag.IMAPTestingAnalysis.empty();
            app.Figures = matlab.ui.Figure.empty();
        end

        % Callback function: HelpPushTool
        function HelpPushToolClicked(app, event)

            % Show progress bar.
            closeProgressBar = app.overlayProgressBar("Generating diagnostics..."); %#ok<NASGU>

            % Initialize variables to save.
            analysis = app.Analysis;

            exportStartDate = app.StartDateTimeDatePicker.Value;
            exportStartTime = app.StartTimeEditField.Value;
            exportEndDate = app.EndDateTimeDatePicker.Value;
            exportEndTime = app.EndTimeEditField.Value;

            selectedPlotType = app.TypeButtonGroup.SelectedObject.Text;
            cropTime = app.CropSpinner.Value;
            psdStartDate = app.PSDStartDateTimeDatePicker.Value;
            psdStartTime = app.PSDStartTimeEditField.Value;
            psdDuration = app.PSDDurationSpinner.Value;

            % Create folder to zip.
            statusFolder = tempname();
            zipFolder = statusFolder + ".zip";

            mkdir(statusFolder);
            deleteFolder = onCleanup(@() rmdir(statusFolder, "s"));

            % Create MAT file with variables.
            save(fullfile(statusFolder, "data.mat"), "analysis", ...
                "exportStartDate", "exportStartTime", "exportEndDate", "exportEndTime", ...
                "selectedPlotType", "cropTime", "psdStartDate", "psdStartTime", "psdDuration");
            exportapp(app.UIFigure, fullfile(statusFolder, "app.png"));

            zip(zipFolder, statusFolder);
            clipboard("copy", zipFolder);

            % Show dialog.
            app.displayAlert(compose("Share ZIP file ""%s""" + newline() + "with the developer. Path copied to clipboard.", zipFolder), "Share Diagnostics", "info");
        end

        % On callback: DebugToggleTool
        function DebugToggleToolOn(app, event)

            app.DebugStatus = dbstatus();

            if ~isempty(app.PreviousError)

                stack = app.PreviousError.stack;
                dbstop("in", stack(1).file, "at", num2str(stack(1).line));
            end
        end

        % Off callback: DebugToggleTool
        function DebugToggleToolOff(app, event)

            dbclear("all");
            dbstop(app.DebugStatus);
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)

            [file, folder] = uigetfile("*.mat", "Import Analysis");

            if ~isequal(file, 0) && ~isequal(folder, 0)

                results = load(fullfile(folder, file));

                for f = string(fieldnames(results))'

                    if isa(results.(f), "mag.IMAPTestingAnalysis")

                        app.Analysis = results.(f);
                        return;
                    end
                end

                app.displayAlert("No ""mag.IMAPTestingAnalysis"" found in MAT file.", "Invalid File Selected", "warning");
            end
        end

        % Value changed function: PerFileDropDown
        function PerFileDropDownValueChanged(app, event)
            app.updateProcessingStepUI("PerFile");
        end

        % Value changed function: WholeDataDropDown
        function WholeDataDropDownValueChanged(app, event)
            app.updateProcessingStepUI("WholeData");
        end

        % Value changed function: ScienceDropDown
        function ScienceDropDownValueChanged(app, event)
            app.updateProcessingStepUI("Science");
        end

        % Value changed function: HKDropDown
        function HKDropDownValueChanged(app, event)
            app.updateProcessingStepUI("HK");
        end

        % Value changed function: RampDropDown
        function RampDropDownValueChanged(app, event)
            app.updateProcessingStepUI("Ramp");
        end

        % Value changed function: VisualizationTypeListBox
        function VisualizationTypeListBoxValueChanged(app, event)

            value = app.VisualizationTypeListBox.ItemsData{app.VisualizationTypeListBox.ValueIndex};

            app.SelectedControl = feval(value, app.VisualizationOptionsPanel);
            app.SelectedControl.instantiate();
        end

        % Button pushed function: ShowFiguresButton
        function ShowFiguresButtonPushed(app, event)

            % Show progress bar.
            closeProgressBar = app.overlayProgressBar("Plotting data..."); %#ok<NASGU>

            % Select plotting function based on plot types.
            try

                if isa(app.SelectedControl, "mag.app.control.AT") || isa(app.SelectedControl, "mag.app.control.CPT")
                    args = {app.Analysis};
                else
                    args = {app.Analysis.Results};
                end

                app.Figures = app.SelectedControl.visualize(args{:});
                return;
            catch exception
                app.displayAlert(exception);
            end
        end

        % Button pushed function: SaveFiguresButton
        function SaveFiguresButtonPushed2(app, event)

            closeProgressBar = app.overlayProgressBar("Saving figures..."); %#ok<NASGU>

            try
                mag.graphics.savePlots(app.Figures, app.ResultsLocation);
            catch exception
                app.displayAlert(exception);
            end
        end

        % Button pushed function: CloseFiguresButton
        function CloseFiguresButtonPushed2(app, event)

            isValidFigures = isvalid(app.Figures);

            if ~isempty(app.Figures) && any(isValidFigures)

                closeProgressBar = app.overlayProgressBar("Closing figures..."); %#ok<NASGU>
                close(app.Figures(isValidFigures));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Import existing analysis'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icons', 'import.png');
            app.DebugToggleTool = uitoggletool(app.Toolbar);
            app.DebugToggleTool.Tooltip = {'Set break point at last error source'};
            app.DebugToggleTool.Icon = fullfile(pathToMLAPP, 'icons', 'debug.png');
            app.DebugToggleTool.Separator = 'on';
            app.DebugToggleTool.OffCallback = createCallbackFcn(app, @DebugToggleToolOff, true);
            app.DebugToggleTool.OnCallback = createCallbackFcn(app, @DebugToggleToolOn, true);
            app.HelpPushTool = uipushtool(app.Toolbar);
            app.HelpPushTool.Tooltip = {'Share debugging information with development'};
            app.HelpPushTool.ClickedCallback = createCallbackFcn(app, @HelpPushToolClicked, true);
            app.HelpPushTool.Icon = fullfile(pathToMLAPP, 'icons', 'help.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AnalyzeTab = uitab(app.TabGroup);
            app.AnalyzeTab.Title = 'Analyze';
            app.AnalyzeLayout = uigridlayout(app.AnalyzeTab);
            app.SettingsPanel = uipanel(app.AnalyzeLayout);
            app.SettingsPanel.Title = 'Settings';
            app.AnalyzeSettingsLayout = uigridlayout(app.SettingsPanel);
            app.LocationEditFieldLabel = uilabel(app.AnalyzeSettingsLayout);
            app.LocationEditFieldLabel.Text = 'Location:';
            app.LocationEditField = uieditfield(app.AnalyzeSettingsLayout, 'text');
            app.BrowseButton = uibutton(app.AnalyzeSettingsLayout, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SciencePatternEditFieldLabel = uilabel(app.AnalyzeSettingsLayout);
            app.SciencePatternEditFieldLabel.Text = 'Science Pattern:';
            app.SciencePatternEditField = uieditfield(app.AnalyzeSettingsLayout, 'text');
            app.HKPatternEditFieldLabel = uilabel(app.AnalyzeSettingsLayout);
            app.HKPatternEditFieldLabel.Text = 'HK Pattern:';
            app.HKPatternEditField = uieditfield(app.AnalyzeSettingsLayout, 'text');
            app.EventPatternEditFieldLabel = uilabel(app.AnalyzeSettingsLayout);
            app.EventPatternEditFieldLabel.Text = 'Event Pattern:';
            app.EventPatternEditField = uieditfield(app.AnalyzeSettingsLayout, 'text');
            app.MetaDataPatternEditFieldLabel = uilabel(app.AnalyzeSettingsLayout);
            app.MetaDataPatternEditFieldLabel.Text = 'Meta Data Pattern:';
            app.MetaDataPatternEditField = uieditfield(app.AnalyzeSettingsLayout, 'text');
            app.IALiRTPatternEditFieldLabel = uilabel(app.AnalyzeSettingsLayout);
            app.IALiRTPatternEditFieldLabel.Text = 'I-ALiRT Pattern:';
            app.IALiRTPatternEditField = uieditfield(app.AnalyzeSettingsLayout, 'text');
            app.ProcessDataButton = uibutton(app.AnalyzeLayout, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessDataButtonPushed, true);
            app.ProcessDataButton.Text = 'Process Data';
            app.ResetButton = uibutton(app.AnalyzeLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.VersionLabel = uilabel(app.AnalyzeLayout);
            app.VersionLabel.Text = '';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ResultsLayout = uigridlayout(app.ResultsTab);
            app.ProcessingStepsPanel = uipanel(app.ResultsLayout);
            app.ProcessingStepsPanel.Enable = 'off';
            app.ProcessingStepsPanel.Title = 'Processing Steps';
            app.StepsLayout = uigridlayout(app.ProcessingStepsPanel);
            app.PerFileLabel = uilabel(app.StepsLayout);
            app.PerFileLabel.Text = 'Per File:';
            app.PerFileDropDown = uidropdown(app.StepsLayout);
            app.PerFileDropDown.Items = {''};
            app.PerFileDropDown.ValueChangedFcn = createCallbackFcn(app, @PerFileDropDownValueChanged, true);
            app.PerFileDropDown.Value = '';
            app.PerFileTextArea = uitextarea(app.StepsLayout);
            app.WholeDataDropDownLabel = uilabel(app.StepsLayout);
            app.WholeDataDropDownLabel.Text = 'Whole Data:';
            app.WholeDataDropDown = uidropdown(app.StepsLayout);
            app.WholeDataDropDown.Items = {''};
            app.WholeDataDropDown.ValueChangedFcn = createCallbackFcn(app, @WholeDataDropDownValueChanged, true);
            app.WholeDataDropDown.Value = '';
            app.ScienceDropDownLabel = uilabel(app.StepsLayout);
            app.ScienceDropDownLabel.Text = 'Science:';
            app.ScienceDropDown = uidropdown(app.StepsLayout);
            app.ScienceDropDown.Items = {''};
            app.ScienceDropDown.ValueChangedFcn = createCallbackFcn(app, @ScienceDropDownValueChanged, true);
            app.ScienceDropDown.Value = '';
            app.HKDropDownLabel = uilabel(app.StepsLayout);
            app.HKDropDownLabel.Text = 'HK:';
            app.HKDropDown = uidropdown(app.StepsLayout);
            app.HKDropDown.Items = {''};
            app.HKDropDown.ValueChangedFcn = createCallbackFcn(app, @HKDropDownValueChanged, true);
            app.HKDropDown.Value = '';
            app.RampModeDropDownLabel = uilabel(app.StepsLayout);
            app.RampModeDropDownLabel.Text = 'Ramp Mode:';
            app.RampDropDown = uidropdown(app.StepsLayout);
            app.RampDropDown.Items = {''};
            app.RampDropDown.ValueChangedFcn = createCallbackFcn(app, @RampDropDownValueChanged, true);
            app.RampDropDown.Value = '';
            app.WholeDataTextArea = uitextarea(app.StepsLayout);
            app.ScienceTextArea = uitextarea(app.StepsLayout);
            app.HKTextArea = uitextarea(app.StepsLayout);
            app.RampTextArea = uitextarea(app.StepsLayout);
            app.MetaDataPanel = uipanel(app.ResultsLayout);
            app.MetaDataPanel.Enable = 'off';
            app.MetaDataPanel.Title = 'Meta Data';
            app.MetaDataLayout = uigridlayout(app.MetaDataPanel);
            app.InstrumentTextArea = uitextarea(app.MetaDataLayout);
            app.InstrumentTextArea.Editable = 'off';
            app.InstrumentTextArea.Tooltip = {'Instrument Meta Data'};
            app.InstrumentTextArea.Placeholder = 'Instrument...';
            app.PrimaryTextArea = uitextarea(app.MetaDataLayout);
            app.PrimaryTextArea.Editable = 'off';
            app.PrimaryTextArea.Tooltip = {'Primary Sensor Meta Data'};
            app.PrimaryTextArea.Placeholder = 'Primary Sensor...';
            app.SecondaryTextArea = uitextarea(app.MetaDataLayout);
            app.SecondaryTextArea.Editable = 'off';
            app.SecondaryTextArea.Tooltip = {'Secondary Sensor Meta Data'};
            app.SecondaryTextArea.Placeholder = 'Secondary Sensor...';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ExportLayout = uigridlayout(app.ExportTab);
            app.ExportButtonsLayout = uigridlayout(app.ExportLayout);
            app.FormatDropDownLabel = uilabel(app.ExportButtonsLayout);
            app.FormatDropDownLabel.Text = 'Format:';
            app.FormatDropDown = uidropdown(app.ExportButtonsLayout);
            app.FormatDropDown.Items = {'Workspace', 'MAT (Full Analysis)', 'MAT (Science Lead)', 'CDF'};
            app.FormatDropDown.Enable = 'off';
            app.FormatDropDown.Value = 'Workspace';
            app.ExportButton = uibutton(app.ExportButtonsLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.ExportNoteLabel = uilabel(app.ExportButtonsLayout);
            app.ExportNoteLabel.Text = {'Note: Export start and end times do not apply'; 'to "Workspace" and "MAT (Full Analysis)"'; 'formats.'};
            app.ExportSettingsPanel = uipanel(app.ExportLayout);
            app.ExportSettingsPanel.Enable = 'off';
            app.ExportSettingsPanel.Title = 'Settings';
            app.ExportSettingsLayout = uigridlayout(app.ExportSettingsPanel);
            app.StartDateTimeDatePickerLabel = uilabel(app.ExportSettingsLayout);
            app.StartDateTimeDatePickerLabel.Text = 'Start Date/Time:';
            app.StartDateTimeDatePicker = uidatepicker(app.ExportSettingsLayout);
            app.StartTimeEditField = uieditfield(app.ExportSettingsLayout, 'text');
            app.StartTimeEditField.Placeholder = 'HH:mm:ss.SSS';
            app.EndDateTimeDatePickerLabel = uilabel(app.ExportSettingsLayout);
            app.EndDateTimeDatePickerLabel.Text = 'End Date/Time:';
            app.EndDateTimeDatePicker = uidatepicker(app.ExportSettingsLayout);
            app.EndTimeEditField = uieditfield(app.ExportSettingsLayout, 'text');
            app.EndTimeEditField.Placeholder = 'HH:mm:ss.SSS';
            app.VisualizeTab = uitab(app.TabGroup);
            app.VisualizeTab.Title = 'Visualize';
            app.VisualizeLayout = uigridlayout(app.VisualizeTab);
            app.VisualizeButtonsLayout = uigridlayout(app.VisualizeLayout);
            app.ShowFiguresButton = uibutton(app.VisualizeButtonsLayout, 'push');
            app.ShowFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFiguresButtonPushed, true);
            app.ShowFiguresButton.Enable = 'off';
            app.ShowFiguresButton.Text = {'Show'; 'Figures'};
            app.SaveFiguresButton = uibutton(app.VisualizeButtonsLayout, 'push');
            app.SaveFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFiguresButtonPushed2, true);
            app.SaveFiguresButton.Enable = 'off';
            app.SaveFiguresButton.Text = {'Save'; 'Figures'};
            app.CloseFiguresButton = uibutton(app.VisualizeButtonsLayout, 'push');
            app.CloseFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFiguresButtonPushed2, true);
            app.CloseFiguresButton.Enable = 'off';
            app.CloseFiguresButton.Text = {'Close'; 'Figures'};
            app.VisualizationOptionsLayout = uigridlayout(app.VisualizeLayout);
            app.VisualizationTypeListBox = uilistbox(app.VisualizationOptionsLayout);
            app.VisualizationTypeListBox.Items = {'AT, SFT', 'CPT', 'Science', 'Spectrogram', 'PSD'};
            app.VisualizationTypeListBox.ItemsData = {'mag.app.control.AT', 'mag.app.control.CPT', 'mag.app.control.Field', 'mag.app.control.Spectrogram', 'mag.app.control.PSD'};
            app.VisualizationTypeListBox.ValueChangedFcn = createCallbackFcn(app, @VisualizationTypeListBoxValueChanged, true);
            app.VisualizationTypeListBox.Enable = 'off';
            app.VisualizationTypeListBox.Value = 'mag.app.control.AT';
            app.VisualizationOptionsPanel = uipanel(app.VisualizationOptionsLayout);
            app.VisualizationOptionsPanel.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataVisualization_AppDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end